classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        ExitButton                     matlab.ui.control.Button
        IntramuscularflowAssessmentwithEsaoteMicroVMeasurementsLabel_2  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        UploadzoneTab                  matlab.ui.container.Tab
        MenuLabel                      matlab.ui.control.Label
        GotocalculationzoneButton      matlab.ui.control.Button
        LoadpostinterventionimageButton  matlab.ui.control.Button
        LoadbaselineimageButton        matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        postAxes                       matlab.ui.control.UIAxes
        preAxes                        matlab.ui.control.UIAxes
        CalculationzoneTab             matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        CuttedimagesTab                matlab.ui.container.Tab
        calc_base_axes                 matlab.ui.control.UIAxes
        calc_post_axes                 matlab.ui.control.UIAxes
        AbsolutedifferenceTab          matlab.ui.container.Tab
        calc_diff_axes_1               matlab.ui.control.UIAxes
        PositiveNegativedifferenceTab  matlab.ui.container.Tab
        calc_diff_axes_3               matlab.ui.control.UIAxes
        calc_diff_axes_2               matlab.ui.control.UIAxes
        res_4                          matlab.ui.control.Label
        NoDifferenceLabel              matlab.ui.control.Label
        res_3                          matlab.ui.control.Label
        res_2                          matlab.ui.control.Label
        NegativeDifferenceLabel        matlab.ui.control.Label
        PositiveDifferenceLabel        matlab.ui.control.Label
        res                            matlab.ui.control.Label
        AbsoluteDifferenceLabel        matlab.ui.control.Label
        CalculateestimatedmicrocirculationButton  matlab.ui.control.Button
        GobacktouploadzoneButton       matlab.ui.control.Button
        MenuLabel_2                    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        baseline_image % Description
        post_image % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadbaselineimageButton
        function LoadbaselineimageButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           if filename==0
           % user pressed cancel
           return
           end
           fullname = [filepath, filename];
           app.baseline_image = imread(fullname);
           axis (app.preAxes,'tight')
           imshow(app.baseline_image, 'Parent' , app.preAxes);

           if((~isempty(app.baseline_image)) && (~isempty(app.post_image)))
               app.GotocalculationzoneButton.Enable = 'on';
           end

        end

        % Button pushed function: LoadpostinterventionimageButton
        function LoadpostinterventionimageButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           if filename==0
           % user pressed cancel
           return
           end
           fullname = [filepath, filename];
           app.post_image = imread(fullname);
           axis(app.postAxes,'tight')
           imshow(app.post_image, 'Parent' , app.postAxes);

           if((~isempty(app.baseline_image)) && (~isempty(app.post_image)))
               app.GotocalculationzoneButton.Enable = 'on';
           end
        end

        % Button pushed function: GotocalculationzoneButton
        function GotocalculationzoneButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CalculationzoneTab;
        end

        % Button pushed function: CalculateestimatedmicrocirculationButton
        function CalculateestimatedmicrocirculationButtonPushed(app, event)


%CUT IMAGES FIRST ON THE GREEN DOTTED LINE

%POST IMAGE CUTTING

rgbchannels = 1:3;
im_post = app.post_image;
[r_post,c_post] = size(app.post_image);
smallestR_post = 999999;
largestR_post = 0;
smallestC_post = 999999;
largestC_post = 0;
%CHECK GREEN COLOR VALUE BORDER IN IMAGE BY LOOPING THROUGH RGB VALUES OF
%IMAGE, IF GREEN DETECTED FIND SMALLEST AND LARGEST X(ROW) AND Y(COLUMN) 
for i = 1:r_post
    for j = 1:r_post
        if(im_post(i,j,2) == 236 && im_post(i,j,3) == 0 && im_post(i,j,1) == 0)
%             fprintf("row is %d and col is %d \n",i,j);            
            if(i < smallestR_post)
                smallestR_post = i;
            end
            if(j > largestR_post)
                largestR_post = i;
            end
            if(j < smallestC_post)
                smallestC_post = j;
            end
            if(j > largestC_post)
                largestC_post = j;
            end
        end
    end
end
% EXTRACT NEW MATRIX FROM ORIGINAL IMAGE WITH FOUNDED SMALLEST/LARGEST ROW
% AND COLUMN VALUES
% +1 and -1 cut the green dots without it we still draw the green dots
cuttedIm_post = im_post(smallestR_post+1:largestR_post-1,smallestC_post+1:largestC_post-1,rgbchannels);


%BASELINE IMAGE CUTTING

im_baseline = app.baseline_image;
[r_baseline,c_baseline] = size(app.baseline_image);
smallestR_baseline = 999999;
largestR_baseline = 0;
smallestC_baseline = 999999;
largestC_baseline = 0;
%CHECK GREEN COLOR VALUE BORDER IN IMAGE BY LOOPING THROUGH RGB VALUES OF
%IMAGE, IF GREEN DETECTED FIND SMALLEST AND LARGEST X(ROW) AND Y(COLUMN) 
for i = 1:r_baseline
    for j = 1:r_baseline
        if(im_baseline(i,j,2) == 236 && im_baseline(i,j,3) == 0 && im_baseline(i,j,1) == 0)
%             fprintf("row is %d and col is %d \n",i,j);            
            if(i < smallestR_baseline)
                smallestR_baseline = i;
            end
            if(j > largestR_baseline)
                largestR_baseline = i;
            end
            if(j < smallestC_baseline)
                smallestC_baseline = j;
            end
            if(j > largestC_baseline)
                largestC_baseline = j;
            end
        end
    end
end
% EXTRACT NEW MATRIX FROM ORIGINAL IMAGE WITH FOUNDED SMALLEST/LARGEST ROW
% AND COLUMN VALUES
% +1 and -1 cut the green dots without it we still draw the green dots
% WHEN USING THE SAME TECHNIQUE FOR THE POST IMAGE ABOVE WHEN WE PRINT THE
% SIZE OF THE MATRIX WE SEE THAT THIS GREEN DOTTED RECTANGLE IS ACTUALLY 1
% PIXEL SMALLER IN HEIGHT SO WE ADD 1 PIXEL TO THIS IMAGE
cuttedIm_baseline = im_baseline(smallestR_baseline+1:largestR_baseline-1,smallestC_baseline+1:largestC_baseline,rgbchannels);


% disp(size(cuttedIm_baseline));
% disp(size(cuttedIm_post));


            %ABSOLUTE DIFFERENCE CHANGE
            Z = imabsdiff(cuttedIm_post,cuttedIm_baseline);

            %DIFFERENCE CHANGE NEGATIVE AND POSITIVE VALUES
            dblSubtractedImage = double(cuttedIm_post) - double(cuttedIm_baseline);
            countPos = length((dblSubtractedImage(dblSubtractedImage>0)));
            countNeg = length((dblSubtractedImage(dblSubtractedImage<0)));
            countZeros = length((dblSubtractedImage(dblSubtractedImage==0)));  
            [r,c] = size(dblSubtractedImage);
            
            % CALCULATION POSITIVE AND NEGATIVE PERCENTAGE             
            positiveChangePercentage = (countPos/(r*c))*100;
            negativeChangePercentage = (countNeg/(r*c))*100;
            noChangePercentage = (countZeros/(r*c))*100;


           % NEGATIVE AND POSITIVE CHANGE IMAGE MODIFICATION
           negaIm = dblSubtractedImage;
           posIm = dblSubtractedImage;

           for i=1:r
                 for j=1:c
                        if( negaIm(i,j) >= 0)
                            negaIm(i,j) = 0;          
                        end
                        if(posIm(i,j) <= 0)
                            posIm(i,j) = 0;
                        end
                 end
            end


            % NEGATIVE IM
            normNeg = abs(negaIm) - min(abs(negaIm(:)));
            normNeg = normNeg ./ max(normNeg(:));
             
            % POSITIVE IM
            normPos = posIm - min(posIm(:));
            normPos = normPos ./ max(normPos(:));

            % VISUALISATION STUFF             
            axis ( app.calc_base_axes,'tight')
            axis ( app.calc_post_axes,'tight')
            axis ( app.calc_diff_axes_1,'tight')
            axis ( app.calc_diff_axes_2,'tight')
            axis ( app.calc_diff_axes_3,'tight')

            colormap(app.calc_diff_axes_1,flipud(turbo));
            colormap(app.calc_diff_axes_2,flipud(turbo));
            colormap(app.calc_diff_axes_3,flipud(turbo));

            colorbar(app.calc_diff_axes_1,"eastoutside");
            colorbar(app.calc_diff_axes_2,"eastoutside");
            colorbar(app.calc_diff_axes_3,"eastoutside");
                     
            imshow(cuttedIm_baseline, 'Parent' , app.calc_base_axes);
            imshow(cuttedIm_post, 'Parent' , app.calc_post_axes);
            imshow(Z, 'Parent' , app.calc_diff_axes_1);
            imshow(normPos, 'Parent' , app.calc_diff_axes_2);
            imshow(normNeg, 'Parent', app.calc_diff_axes_3);


            % CALCULATION ABSOLUTE DIFFERENCE PERCENTAGE 
            nonzeros = nnz(Z);
            zeros = nnz(~Z);            
            percentage_in_difference = (nonzeros/(zeros+nonzeros))*100;

            % SHOW RESULTS IN UI            
            app.res.Text = string(percentage_in_difference);
            app.res_2.Text = string(positiveChangePercentage);
            app.res_3.Text = string(negativeChangePercentage);
            app.res_4.Text = string(noChangePercentage);

        end

        % Button pushed function: GobacktouploadzoneButton
        function GobacktouploadzoneButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.UploadzoneTab;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UploadzoneTab = uitab(app.TabGroup);
            app.UploadzoneTab.Title = 'Upload zone';
            app.preAxes = uiaxes(app.UploadzoneTab);
            title(app.preAxes, 'Baseline image')
            zlabel(app.preAxes, 'Z')
            app.preAxes.XTick = [];
            app.preAxes.XTickLabel = '';
            app.preAxes.YTick = [];
            app.preAxes.YTickLabel = '';
            app.postAxes = uiaxes(app.UploadzoneTab);
            title(app.postAxes, 'Post intervention image')
            zlabel(app.postAxes, 'Z')
            app.postAxes.XTick = [];
            app.postAxes.XTickLabel = '';
            app.postAxes.YTick = [];
            app.postAxes.YTickLabel = '';
            app.Label_2 = uilabel(app.UploadzoneTab);
            app.Label_2.Text = '';
            app.LoadbaselineimageButton = uibutton(app.UploadzoneTab, 'push');
            app.LoadbaselineimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadbaselineimageButtonPushed, true);
            app.LoadbaselineimageButton.Text = 'Load baseline image';
            app.LoadpostinterventionimageButton = uibutton(app.UploadzoneTab, 'push');
            app.LoadpostinterventionimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpostinterventionimageButtonPushed, true);
            app.LoadpostinterventionimageButton.Text = 'Load post intervention image';
            app.GotocalculationzoneButton = uibutton(app.UploadzoneTab, 'push');
            app.GotocalculationzoneButton.ButtonPushedFcn = createCallbackFcn(app, @GotocalculationzoneButtonPushed, true);
            app.GotocalculationzoneButton.Enable = 'off';
            app.GotocalculationzoneButton.Text = 'Go to calculation zone';
            app.MenuLabel = uilabel(app.UploadzoneTab);
            app.MenuLabel.Text = 'Menu';
            app.CalculationzoneTab = uitab(app.TabGroup);
            app.CalculationzoneTab.Title = 'Calculation zone';
            app.Label_3 = uilabel(app.CalculationzoneTab);
            app.Label_3.Text = '';
            app.MenuLabel_2 = uilabel(app.CalculationzoneTab);
            app.MenuLabel_2.Text = 'Menu';
            app.GobacktouploadzoneButton = uibutton(app.CalculationzoneTab, 'push');
            app.GobacktouploadzoneButton.ButtonPushedFcn = createCallbackFcn(app, @GobacktouploadzoneButtonPushed, true);
            app.GobacktouploadzoneButton.Text = 'Go back to upload zone';
            app.CalculateestimatedmicrocirculationButton = uibutton(app.CalculationzoneTab, 'push');
            app.CalculateestimatedmicrocirculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateestimatedmicrocirculationButtonPushed, true);
            app.CalculateestimatedmicrocirculationButton.Text = 'Calculate estimated microcirculation';
            app.AbsoluteDifferenceLabel = uilabel(app.CalculationzoneTab);
            app.AbsoluteDifferenceLabel.Text = 'Absolute Difference (%)  : ';
            app.res = uilabel(app.CalculationzoneTab);
            app.res.Text = '';
            app.PositiveDifferenceLabel = uilabel(app.CalculationzoneTab);
            app.PositiveDifferenceLabel.Text = 'Positive Difference (%) :   ';
            app.NegativeDifferenceLabel = uilabel(app.CalculationzoneTab);
            app.NegativeDifferenceLabel.Text = 'Negative Difference (%) : ';
            app.res_2 = uilabel(app.CalculationzoneTab);
            app.res_2.Text = '';
            app.res_3 = uilabel(app.CalculationzoneTab);
            app.res_3.Text = '';
            app.NoDifferenceLabel = uilabel(app.CalculationzoneTab);
            app.NoDifferenceLabel.Text = 'No Difference (%) : ';
            app.res_4 = uilabel(app.CalculationzoneTab);
            app.res_4.Text = '';
            app.TabGroup2 = uitabgroup(app.CalculationzoneTab);
            app.CuttedimagesTab = uitab(app.TabGroup2);
            app.CuttedimagesTab.Title = 'Cutted images';
            app.calc_post_axes = uiaxes(app.CuttedimagesTab);
            title(app.calc_post_axes, 'Auto cutted post intervention image')
            app.calc_post_axes.XTick = [];
            app.calc_post_axes.XTickLabel = '';
            app.calc_post_axes.YTick = [];
            app.calc_post_axes.YTickLabel = '';
            app.calc_base_axes = uiaxes(app.CuttedimagesTab);
            title(app.calc_base_axes, 'Auto cutted baseline image')
            app.calc_base_axes.XTick = [];
            app.calc_base_axes.XTickLabel = '';
            app.calc_base_axes.YTick = [];
            app.calc_base_axes.YTickLabel = '';
            app.AbsolutedifferenceTab = uitab(app.TabGroup2);
            app.AbsolutedifferenceTab.Title = 'Absolute difference';
            app.calc_diff_axes_1 = uiaxes(app.AbsolutedifferenceTab);
            title(app.calc_diff_axes_1, 'Absolute difference image')
            app.calc_diff_axes_1.XTick = [];
            app.calc_diff_axes_1.XTickLabel = '';
            app.calc_diff_axes_1.YTick = [];
            app.calc_diff_axes_1.YTickLabel = '';
            app.calc_diff_axes_1.SubtitleFontWeight = 'bold';
            app.PositiveNegativedifferenceTab = uitab(app.TabGroup2);
            app.PositiveNegativedifferenceTab.Title = 'Positive/Negative difference';
            app.calc_diff_axes_2 = uiaxes(app.PositiveNegativedifferenceTab);
            title(app.calc_diff_axes_2, 'Positive difference image')
            app.calc_diff_axes_2.XTick = [];
            app.calc_diff_axes_2.XTickLabel = '';
            app.calc_diff_axes_2.YTick = [];
            app.calc_diff_axes_2.YTickLabel = '';
            app.calc_diff_axes_3 = uiaxes(app.PositiveNegativedifferenceTab);
            title(app.calc_diff_axes_3, 'Negative difference image')
            app.calc_diff_axes_3.XTick = [];
            app.calc_diff_axes_3.YTick = [];
            app.IntramuscularflowAssessmentwithEsaoteMicroVMeasurementsLabel_2 = uilabel(app.UIFigure);
            app.IntramuscularflowAssessmentwithEsaoteMicroVMeasurementsLabel_2.Text = 'Intramuscular flow Assessment with Esaote MicroV Measurements';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ua_aesthetic.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end